classdef cmeditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RGBcoloreditorUIFigure    matlab.ui.Figure
        GrayScaleEditField        matlab.ui.control.EditField
        GrayScaleEditFieldLabel   matlab.ui.control.Label
        HSVEditField              matlab.ui.control.EditField
        HSVEditFieldLabel         matlab.ui.control.Label
        DecTripletEditField       matlab.ui.control.EditField
        DecTripletEditFieldLabel  matlab.ui.control.Label
        HexEditField              matlab.ui.control.EditField
        HexEditFieldLabel         matlab.ui.control.Label
        BlueValue                 matlab.ui.control.EditField
        valueEditField_3Label     matlab.ui.control.Label
        GreenValue                matlab.ui.control.EditField
        valueEditField_2Label     matlab.ui.control.Label
        RedValue                  matlab.ui.control.EditField
        valueEditFieldLabel       matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        BlueSlider                matlab.ui.control.Slider
        BlueSliderLabel           matlab.ui.control.Label
        GreenSlider               matlab.ui.control.Slider
        GreenSliderLabel          matlab.ui.control.Label
        RedSlider                 matlab.ui.control.Slider
        RedSliderLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        Rindex='00'; % Description
        Gindex='00';
        Bindex='00';
     LampColor;

    end
    
    methods (Access = private)
        
        function  Rgb2HSV(app)
            rv=uint8(str2double(app.RedValue.Value));
            gv=uint8(str2double(app.GreenValue.Value));
            bv=uint8(str2double(app.BlueValue.Value));
%             RR=repmat(rv,2,2);
%             GG=repmat(gv,2,2);
%             BB=repmat(bv,2,2);
            timg(:,:,1)=rv;
             timg(:,:,2)=gv;
              timg(:,:,3)=bv;


              himg=rgb2hsv(timg);
              hv=himg(:,:,1);
              sv=himg(:,:,2);
              vv=himg(:,:,3);
              hsvstr=['[',num2str(hv),', ',num2str(sv),', ',num2str(vv),']'];
              app.HSVEditField.Value=hsvstr; 
              gralev=0.229*rv+0.587*gv+0.114*bv;
              app.GrayScaleEditField.Value=num2str(gralev);
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value;
              app.RedValue.Value=num2str(uint8(value));
            if value>16
             app.Rindex=dec2hex(uint8(value));
            else
             app.Rindex=['0',dec2hex(uint8(value))];
            end
          
            app.LampColor=['#',app.Rindex,app.Gindex,app.Bindex];
            app.Lamp.Color=app.LampColor;
%             app.HexValue.Text=app.LampColor;
              app.HexEditField.Value=app.LampColor;
             text=strcat('[',app.RedValue.Value,',',app.GreenValue.Value,',',app.BlueValue.Value,']');
             app.DecTripletEditField.Value=text;
%             app.DecValue.Text=text;
app.Rgb2HSV;

        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value;
              app.GreenValue.Value=num2str(uint8(value));
           if value>16
             app.Gindex=dec2hex(uint8(value));
            else
             app.Gindex=['0',dec2hex(uint8(value))];
            end

            app.LampColor=['#',app.Rindex,app.Gindex,app.Bindex];
            app.Lamp.Color=app.LampColor;
             app.HexEditField.Value=app.LampColor;
                          text=strcat('[',app.RedValue.Value,',',app.GreenValue.Value,',',app.BlueValue.Value,']');
             app.DecTripletEditField.Value=text;
             app.Rgb2HSV;

        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value;
              app.BlueValue.Value=num2str(uint8(value));
            if value>16
             app.Bindex=dec2hex(uint8(value));
            else
             app.Bindex=['0',dec2hex(uint8(value))];
            end
              app.LampColor=['#',app.Rindex,app.Gindex,app.Bindex];
            app.Lamp.Color=app.LampColor;
             app.HexEditField.Value=app.LampColor;
                          text=strcat('[',app.RedValue.Value,',',app.GreenValue.Value,',',app.BlueValue.Value,']');
             app.DecTripletEditField.Value=text;
             app.Rgb2HSV;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RGBcoloreditorUIFigure = uifigure('Visible', 'off');
            app.RGBcoloreditorUIFigure.Name = 'RGB color editor';
            app.RGBcoloreditorUIFigure.Icon = 'images.jpg';
            app.RedSliderLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.RGBcoloreditorUIFigure);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.RGBcoloreditorUIFigure);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.RGBcoloreditorUIFigure);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.Lamp = uilamp(app.RGBcoloreditorUIFigure);
            app.valueEditFieldLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.valueEditFieldLabel.Text = 'value';
            app.RedValue = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            app.RedValue.Editable = 'off';
            app.RedValue.Value = '0';
            app.valueEditField_2Label = uilabel(app.RGBcoloreditorUIFigure);
            app.valueEditField_2Label.Text = 'value';
            app.GreenValue = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            app.GreenValue.Editable = 'off';
            app.GreenValue.Value = '0';
            app.valueEditField_3Label = uilabel(app.RGBcoloreditorUIFigure);
            app.valueEditField_3Label.Text = 'value';
            app.BlueValue = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            app.BlueValue.Editable = 'off';
            app.BlueValue.Value = '0';
            app.HexEditFieldLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.HexEditFieldLabel.Text = 'Hex :';
            app.HexEditField = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            app.HexEditField.Editable = 'off';
            app.DecTripletEditFieldLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.DecTripletEditFieldLabel.Text = 'Dec Triplet:';
            app.DecTripletEditField = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            app.DecTripletEditField.Editable = 'off';
            app.HSVEditFieldLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.HSVEditFieldLabel.Text = 'HSV:';
            app.HSVEditField = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            app.GrayScaleEditFieldLabel = uilabel(app.RGBcoloreditorUIFigure);
            app.GrayScaleEditFieldLabel.Text = 'GrayScale:';
            app.GrayScaleEditField = uieditfield(app.RGBcoloreditorUIFigure, 'text');
            % Show the figure after all components are created
            app.RGBcoloreditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cmeditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RGBcoloreditorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RGBcoloreditorUIFigure)
        end
    end
end